classdef app_testbench21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        COMPortPowersupplyEditFieldLabel  matlab.ui.control.Label
        COMPort_psupply                 matlab.ui.control.EditField
        COMPortLoadEditFieldLabel       matlab.ui.control.Label
        COMPort_load                    matlab.ui.control.EditField
        dischargeCurrentLabel           matlab.ui.control.Label
        dischargeCurrent                matlab.ui.control.NumericEditField
        CellTestbenchLabel              matlab.ui.control.Label
        chargeVoltageLabel              matlab.ui.control.Label
        chargeVoltage                   matlab.ui.control.NumericEditField
        dischargeVoltageLabel           matlab.ui.control.Label
        dischargeVoltage                matlab.ui.control.NumericEditField
        COMPorttemp_measEditFieldLabel  matlab.ui.control.Label
        COMPorttemp_meas                matlab.ui.control.EditField
        chargeCurrentLabel              matlab.ui.control.Label
        chargecurrent                   matlab.ui.control.NumericEditField
        MeasFunctionButtonGroup         matlab.ui.container.ButtonGroup
        chargecellButton                matlab.ui.control.ToggleButton
        dischargecellButton             matlab.ui.control.ToggleButton
        meascapacityButton              matlab.ui.control.ToggleButton
        runprofileButton                matlab.ui.control.ToggleButton
        chargetoSOCButton               matlab.ui.control.ToggleButton
        recordOCVButton                 matlab.ui.control.ToggleButton
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField2Label                 matlab.ui.control.Label
        EditField2                      matlab.ui.control.EditField
        EditField3Label                 matlab.ui.control.Label
        EditField3                      matlab.ui.control.EditField
        endCurrentLabel                 matlab.ui.control.Label
        endcurrent                      matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
    end

    
    methods (Access = private)
    
    end
    
    methods (Access = public)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('src\');
            delete(instrfindall()); %close open com-ports
            global stop_var
            global over_temperature_var
            stop_var=0;
            over_temperature_var=0;
        end

        % Selection changed function: MeasFunctionButtonGroup
        function MeasFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.MeasFunctionButtonGroup.SelectedObject;
            if (selectedButton==app.chargecellButton)||(selectedButton==app.dischargecellButton)
                app.EditField.Visible=false;
                app.EditField2.Visible=false;
                app.EditField3.Visible=false;
                app.EditFieldLabel.Visible=false;
                app.EditField2Label.Visible=false;
                app.EditField3Label.Visible=false;
            elseif selectedButton==app.meascapacityButton
                app.EditField3.Visible=true;
                app.EditField3Label.Visible=true;
                app.EditField3Label.Text='measured capacity';
                app.EditField.Visible=false;
                app.EditField2.Visible=false;
                app.EditFieldLabel.Visible=false;
                app.EditField2Label.Visible=false;
            elseif selectedButton==app.recordOCVButton
                app.EditField.Visible=true;
                app.EditFieldLabel.Visible=true;
                app.EditFieldLabel.Text='discharge time';
                app.EditField2.Visible=true;
                app.EditField2Label.Visible=true;
                app.EditField2Label.Text='ocv time';
                app.EditField3.Visible=false;
                app.EditField3Label.Visible=false;
            elseif selectedButton==app.chargetoSOCButton
                app.EditField.Visible=true;
                app.EditFieldLabel.Visible=true;
                app.EditFieldLabel.Text='desired soc';
                app.EditField2.Visible=true;
                app.EditField2Label.Visible=true;
                app.EditField2Label.Text='cell capacity';
                app.EditField3.Visible=false;
                app.EditField3Label.Visible=false;
            elseif selectedButton==app.runprofileButton
                app.EditField.Visible=true;
                app.EditFieldLabel.Visible=true;
                app.EditFieldLabel.Text='path to profile';
                app.EditField2.Visible=true;
                app.EditField2Label.Visible=true;
                app.EditField2Label.Text='frequency';
                app.EditField3.Visible=false;
                app.EditField3Label.Visible=false;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global stop_var
            stop_var=0;
            com_load=app.COMPort_load.Value;
            com_psupply=app.COMPort_psupply.Value;
            com_temperature=app.COMPorttemp_meas.Value;
            discharge_voltage=app.dischargeVoltage.Value;
            discharge_current=app.dischargeCurrent.Value;
            charge_voltage=app.chargeVoltage.Value;
            charge_current=app.chargecurrent.Value;
            end_current=app.endcurrent.Value;
            
            selectedFunction=app.MeasFunctionButtonGroup.SelectedObject;
            if selectedFunction==app.chargecellButton
                device_load=serial(com_load,'BaudRate',115200);
                obj_load=load_act;
                device_psupply=serial(com_psupply,'BaudRate',57600,'Parity','odd');
                obj_psupply=psupply_act;
                device_temperature=serialport(com_temperature,9600);
                start_charge=0;
                start_time=0;
                plot_ctrl=plot_controller;
                generate_plots(plot_ctrl);
                charge_cell(device_load,obj_load,device_psupply,obj_psupply,device_temperature,charge_voltage,charge_current,start_charge,start_time,end_current,plot_ctrl)
                delete(instrfindall)
            elseif selectedFunction==app.dischargecellButton
                device_load=serial(com_load,'BaudRate',115200);
                obj_load=load_act;
                device_psupply=serial(com_psupply,'BaudRate',57600,'Parity','odd');
                obj_psupply=psupply_act;
                device_temperature=serialport(com_temperature,9600);
                start_charge=0;
                start_time=0;
                plot_ctrl=plot_controller;
                generate_plots(plot_ctrl);
                discharge_cell(device_load,obj_load,device_psupply,obj_psupply,device_temperature,charge_voltage,charge_current,start_charge,start_time,end_current,plot_ctrl)
                delete(instrfindall)
            elseif selectedFunction==app.meascapacityButton
                C = meas_capacity(com_load,com_psupply,com_temperature,discharge_voltage,charge_voltage,discharge_current,charge_current,end_current);
                app.EditField3.Value = C;
            elseif selectedFunction==app.recordOCVButton
                discharge_time=app.EditField.Value;
                ocv_time=app.EditField2.Value;
                record_ocv(com_load,com_psupply,com_temperature,discharge_voltage,charge_voltage,discharge_current,charge_current,end_current,discharge_time,ocv_time)
            elseif selectedFunction==app.chargetoSOCButton
                desired_soc=app.EditField.Value;
                cell_capacity=app.EditField2.Value;
                charge_to_SOC(com_load,com_psupply,com_temperature,discharge_voltage,charge_voltage,discharge_current,charge_current,end_current,desired_soc,cell_capacity)
            elseif selectedFunction==app.runprofileButton
                profile=app.EditField.Value;
                frequency=app.EditField2.Value;
                run_profile(com_load,com_psupply,com_temperature,discharge_voltage,charge_voltage,discharge_current,charge_current,profile,frequency)
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                com_load=app.COMPort_load.Value;
                com_psupply=app.COMPort_psupply.Value;
                device_load=serial(com_load,'BaudRate',115200);
                obj_load=load_act;
                device_psupply=serial(com_psupply,'BaudRate',57600,'Parity','odd');
                obj_psupply=psupply_act;
                set_loadInput(obj_load,device_load,0)
                set_psupplyOutput(obj_psupply,device_psupply,0)
                set_psupplyRemote(obj_psupply,device_psupply,0)
                delete(instrfindall())
            catch
                disp('error with com-ports')
            end
            delete(instrfindall())
            delete(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop_var
            stop_var=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.COMPortPowersupplyEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortPowersupplyEditFieldLabel.Text = 'COM-Port Powersupply';
            app.COMPort_psupply = uieditfield(app.UIFigure, 'text');
            app.COMPort_psupply.Value = 'COM4';
            app.COMPortLoadEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortLoadEditFieldLabel.Text = 'COM-Port Load';
            app.COMPort_load = uieditfield(app.UIFigure, 'text');
            app.COMPort_load.Value = 'COM5';
            app.dischargeCurrentLabel = uilabel(app.UIFigure);
            app.dischargeCurrentLabel.Text = 'discharge Current';
            app.dischargeCurrent = uieditfield(app.UIFigure, 'numeric');
            app.dischargeCurrent.Value = 5;
            app.CellTestbenchLabel = uilabel(app.UIFigure);
            app.CellTestbenchLabel.Text = 'Cell-Testbench';
            app.chargeVoltageLabel = uilabel(app.UIFigure);
            app.chargeVoltageLabel.Text = 'charge Voltage';
            app.chargeVoltage = uieditfield(app.UIFigure, 'numeric');
            app.chargeVoltage.Value = 4.2;
            app.dischargeVoltageLabel = uilabel(app.UIFigure);
            app.dischargeVoltageLabel.Text = 'discharge Voltage';
            app.dischargeVoltage = uieditfield(app.UIFigure, 'numeric');
            app.dischargeVoltage.Value = 3;
            app.COMPorttemp_measEditFieldLabel = uilabel(app.UIFigure);
            app.COMPorttemp_measEditFieldLabel.Text = 'COMPort temp_meas';
            app.COMPorttemp_meas = uieditfield(app.UIFigure, 'text');
            app.chargeCurrentLabel = uilabel(app.UIFigure);
            app.chargeCurrentLabel.Text = 'charge Current';
            app.chargecurrent = uieditfield(app.UIFigure, 'numeric');
            app.chargecurrent.Value = 1;
            app.MeasFunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.MeasFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MeasFunctionButtonGroupSelectionChanged, true);
            app.MeasFunctionButtonGroup.Title = 'Meas Function';
            app.chargecellButton = uitogglebutton(app.MeasFunctionButtonGroup);
            app.chargecellButton.Text = 'charge cell';
            app.chargecellButton.Value = true;
            app.dischargecellButton = uitogglebutton(app.MeasFunctionButtonGroup);
            app.dischargecellButton.Text = 'discharge cell';
            app.meascapacityButton = uitogglebutton(app.MeasFunctionButtonGroup);
            app.meascapacityButton.Text = 'meas capacity';
            app.runprofileButton = uitogglebutton(app.MeasFunctionButtonGroup);
            app.runprofileButton.Text = 'run profile';
            app.chargetoSOCButton = uitogglebutton(app.MeasFunctionButtonGroup);
            app.chargetoSOCButton.Text = 'charge to SOC';
            app.recordOCVButton = uitogglebutton(app.MeasFunctionButtonGroup);
            app.recordOCVButton.Text = 'record OCV';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Visible = 'off';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Visible = 'off';
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Visible = 'off';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Visible = 'off';
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField3.Editable = 'off';
            app.EditField3.Visible = 'off';
            app.endCurrentLabel = uilabel(app.UIFigure);
            app.endCurrentLabel.Text = 'end Current';
            app.endcurrent = uieditfield(app.UIFigure, 'numeric');
            app.endcurrent.Value = 0.02;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_testbench21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end